classdef F20 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        VehiclePropertiesTab            matlab.ui.container.Tab
        SpringsPanel                    matlab.ui.container.Panel
        FrontSpringRateinlbfEditFieldLabel  matlab.ui.control.Label
        FrontSpringRate                 matlab.ui.control.NumericEditField
        RearSpringRateinlbfEditFieldLabel  matlab.ui.control.Label
        RearSpringRate                  matlab.ui.control.NumericEditField
        ShocksPanel                     matlab.ui.container.Panel
        ShockFullLengthinEditFieldLabel  matlab.ui.control.Label
        ShockFullLengthinEditField      matlab.ui.control.NumericEditField
        MRRearShockWheelEditFieldLabel  matlab.ui.control.Label
        MRRear                          matlab.ui.control.NumericEditField
        MRFrontShockWheelLabel          matlab.ui.control.Label
        MRFront                         matlab.ui.control.NumericEditField
        OverallVehiclePanel             matlab.ui.container.Panel
        FrontTrackinEditFieldLabel      matlab.ui.control.Label
        FrontTrack                      matlab.ui.control.NumericEditField
        RearTrackinEditFieldLabel       matlab.ui.control.Label
        RearTrack                       matlab.ui.control.NumericEditField
        CGHeightinEditFieldLabel        matlab.ui.control.Label
        CGHeight                        matlab.ui.control.NumericEditField
        SaveVehiclePropsButton          matlab.ui.control.Button
        SingleComp                      matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        DamperPlotsTab                  matlab.ui.container.Tab
        FrontGraphTemplatesDropDownLabel  matlab.ui.control.Label
        DamperGraphTemplatesDropDown    matlab.ui.control.DropDown
        RefreshPlotButton               matlab.ui.control.Button
        DetachPlotButton                matlab.ui.control.Button
        DamperAxes                      matlab.ui.control.UIAxes
        FLDamper                        matlab.ui.control.CheckBox
        FRDamper                        matlab.ui.control.CheckBox
        RLDamper                        matlab.ui.control.CheckBox
        RRDamper                        matlab.ui.control.CheckBox
        DamperStatsTab                  matlab.ui.container.Tab
        FrontDamperTable                matlab.ui.control.Table
        RearDamperTable                 matlab.ui.control.Table
        test3Tab                        matlab.ui.container.Tab
        FilesLoadedDropDownLabel        matlab.ui.control.Label
        FilesLoadedDropDown             matlab.ui.control.DropDown
        LoadingFilesLabel               matlab.ui.control.Label
        DualComparisonTab               matlab.ui.container.Tab
        LoadDataButton_2                matlab.ui.control.Button
        FilesLoadedListBox_2Label       matlab.ui.control.Label
        FilesLoadedListBox_2            matlab.ui.control.ListBox
        Tab                             matlab.ui.container.Tab
    end

    
    properties (Access = private)
        filesLoaded = {}; % Description
        carParams = struct;
        graphOpts = {};
    end
    
    methods (Access = private)
        
        function setDefaults(app)
            app.FrontSpringRate.Value = app.carParams.frontSpringRate;
            app.RearSpringRate.Value = app.carParams.rearSpringRate;
            app.ShockFullLengthinEditField.Value = app.carParams.shockLength;
            app.FrontTrack.Value = app.carParams.frontTrack;
            app.RearTrack.Value = app.carParams.rearTrack;
            app.CGHeight.Value = app.carParams.cg;
            app.MRFront.Value = app.carParams.MRFront;
            app.MRRear.Value = app.carParams.MRRear;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                load('CarParameters.mat')
                app.carParams = carParams;
                setDefaults(app);
            catch
                answer = questdlg('No car parameters found, select one from another folder?','Car Selection','Yes','No','Yes');
                if strcmpi(answer,'yes')
                    [file, path] = uigetfile('.mat');
                    try
                        load([path file])
                        app.carParams = carParams;
                        setDefaults(app);
                    catch
                    end
                end
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.LoadingFilesLabel.Visible = true;
            try
                [file, path] = uigetfile('.xlsx','MultiSelect','on');
                if ~iscell(file)
                    file = {file};
                end
                for i = 1:length(file)
                    fullFileName = [path file{i}];
                    fileName = file{i};
                    dataObj = struct;
                    dataObj.damperData = Damper_Analysis_Vehicle_Data_GUI(fullFileName);
                    %TODO add other data sets
                    if contains(app.FilesLoadedDropDown.Items,fileName)
                        index = strcmp(app.filesLoaded(:,1),fileName);
                        app.filesLoaded{index,1} = fileName;
                        app.filesLoaded{index,2} = dataObj;
                    else
                        app.filesLoaded = [app.filesLoaded; {fileName, dataObj}];
                    end
                end
                if length(app.filesLoaded(:,1)) == 1
                    app.FilesLoadedDropDown.Items = app.filesLoaded(1,1);
                else
                    app.FilesLoadedDropDown.Items = app.filesLoaded(:,1);
                end
                
            catch ex
                errordlg('Can''t load file(s).')
                disp(ex)
            end
            app.LoadingFilesLabel.Visible = false;
        end

        % Callback function
        function FilesLoadedDropDownValueChanged(app, event)
            value = app.FilesLoadedDropDown.Value;
            
        end

        % Button pushed function: SaveVehiclePropsButton
        function SaveVehiclePropsButtonPushed(app, event)
            app.carParams.frontSpringRate = app.FrontSpringRate.Value;
            app.carParams.rearSpringRate = app.RearSpringRate.Value;
            app.carParams.shockLength = app.ShockFullLengthinEditField.Value;
            app.carParams.frontTrack = app.FrontTrack.Value;
            app.carParams.rearTrack = app.RearTrack.Value;
            app.carParams.cg = app.CGHeight.Value;
            app.carParams.MRFront = app.MRFront.Value;
            app.carParams.MRRear = app.MRRear.Value;
            carParams = app.carParams;
            save('CarParameters.mat','carParams');
            clear carParams;
        end

        % Callback function: DamperGraphTemplatesDropDown, FLDamper, 
        % FRDamper, RLDamper, RRDamper, RefreshPlotButton
        function RefreshDamperPlotsButtonPushed(app, event)
            try
                index = strcmp(app.filesLoaded(:,1),app.FilesLoadedDropDown.Value);
                dataObj = app.filesLoaded{index,2};
                refreshDamperPlot(app,app.DamperAxes,dataObj.damperData);
            catch ex
                errordlg('Can''t refresh plots.');
                disp (ex)
            end
        end

        % Button pushed function: DetachPlotButton
        function DetachPlotButtonPushed(app, event)
            try
                index = strcmp(app.filesLoaded(:,1),app.FilesLoadedDropDown.Value);
                dataObj = app.filesLoaded{index,2};
                figure
                ax = gca;
                refreshDamperPlot(app,ax,dataObj.damperData);
            catch ex
                errordlg('Can''t detach plots.');
                disp(ex)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VehiclePropertiesTab = uitab(app.TabGroup);
            app.VehiclePropertiesTab.Title = 'Vehicle Properties';
            app.SpringsPanel = uipanel(app.VehiclePropertiesTab);
            app.SpringsPanel.Title = 'Springs';
            app.FrontSpringRateinlbfEditFieldLabel = uilabel(app.SpringsPanel);
            app.FrontSpringRateinlbfEditFieldLabel.Text = {'Front Spring Rate (in/lbf)'; ''};
            app.FrontSpringRate = uieditfield(app.SpringsPanel, 'numeric');
            app.FrontSpringRate.ValueDisplayFormat = '%.0f';
            app.RearSpringRateinlbfEditFieldLabel = uilabel(app.SpringsPanel);
            app.RearSpringRateinlbfEditFieldLabel.Text = {'Rear Spring Rate (in/lbf)'; ''};
            app.RearSpringRate = uieditfield(app.SpringsPanel, 'numeric');
            app.ShocksPanel = uipanel(app.VehiclePropertiesTab);
            app.ShocksPanel.Title = 'Shocks';
            app.ShockFullLengthinEditFieldLabel = uilabel(app.ShocksPanel);
            app.ShockFullLengthinEditFieldLabel.Text = {'Shock Full Length (in)'; ''};
            app.ShockFullLengthinEditField = uieditfield(app.ShocksPanel, 'numeric');
            app.MRRearShockWheelEditFieldLabel = uilabel(app.ShocksPanel);
            app.MRRearShockWheelEditFieldLabel.Text = {'MR Rear (Shock/Wheel)'; ''};
            app.MRRear = uieditfield(app.ShocksPanel, 'numeric');
            app.MRFrontShockWheelLabel = uilabel(app.ShocksPanel);
            app.MRFrontShockWheelLabel.Text = {'MR Front (Shock/Wheel)'; ''};
            app.MRFront = uieditfield(app.ShocksPanel, 'numeric');
            app.OverallVehiclePanel = uipanel(app.VehiclePropertiesTab);
            app.OverallVehiclePanel.Title = 'Overall Vehicle';
            app.FrontTrackinEditFieldLabel = uilabel(app.OverallVehiclePanel);
            app.FrontTrackinEditFieldLabel.Text = {'Front Track (in)'; ''};
            app.FrontTrack = uieditfield(app.OverallVehiclePanel, 'numeric');
            app.RearTrackinEditFieldLabel = uilabel(app.OverallVehiclePanel);
            app.RearTrackinEditFieldLabel.Text = {'Rear Track (in)'; ''; ''; ''};
            app.RearTrack = uieditfield(app.OverallVehiclePanel, 'numeric');
            app.CGHeightinEditFieldLabel = uilabel(app.OverallVehiclePanel);
            app.CGHeightinEditFieldLabel.Text = {'CG Height (in)'; ''; ''};
            app.CGHeight = uieditfield(app.OverallVehiclePanel, 'numeric');
            app.SaveVehiclePropsButton = uibutton(app.VehiclePropertiesTab, 'push');
            app.SaveVehiclePropsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveVehiclePropsButtonPushed, true);
            app.SaveVehiclePropsButton.Text = 'Save Car';
            app.SingleComp = uitab(app.TabGroup);
            app.SingleComp.Title = 'Single Comparison';
            app.LoadDataButton = uibutton(app.SingleComp, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = {'Load Data'; ''};
            app.TabGroup2 = uitabgroup(app.SingleComp);
            app.DamperPlotsTab = uitab(app.TabGroup2);
            app.DamperPlotsTab.Title = 'Damper Plots';
            app.FrontGraphTemplatesDropDownLabel = uilabel(app.DamperPlotsTab);
            app.FrontGraphTemplatesDropDownLabel.Text = 'Front Graph Templates';
            app.DamperGraphTemplatesDropDown = uidropdown(app.DamperPlotsTab);
            app.DamperGraphTemplatesDropDown.Items = {'Histogram Raw', 'Histogram Trimmed', ''};
            app.DamperGraphTemplatesDropDown.ValueChangedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.DamperGraphTemplatesDropDown.Value = 'Histogram Raw';
            app.RefreshPlotButton = uibutton(app.DamperPlotsTab, 'push');
            app.RefreshPlotButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.RefreshPlotButton.Text = {'Refresh Plot'; ''};
            app.DetachPlotButton = uibutton(app.DamperPlotsTab, 'push');
            app.DetachPlotButton.ButtonPushedFcn = createCallbackFcn(app, @DetachPlotButtonPushed, true);
            app.DetachPlotButton.Text = 'Detach Plot';
            app.DamperAxes = uiaxes(app.DamperPlotsTab);
            title(app.DamperAxes, 'Title')
            xlabel(app.DamperAxes, 'X')
            ylabel(app.DamperAxes, 'Y')
            app.FLDamper = uicheckbox(app.DamperPlotsTab);
            app.FLDamper.ValueChangedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.FLDamper.Text = 'Front Left';
            app.FRDamper = uicheckbox(app.DamperPlotsTab);
            app.FRDamper.ValueChangedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.FRDamper.Text = 'Front Right';
            app.RLDamper = uicheckbox(app.DamperPlotsTab);
            app.RLDamper.ValueChangedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.RLDamper.Text = 'Rear Left';
            app.RRDamper = uicheckbox(app.DamperPlotsTab);
            app.RRDamper.ValueChangedFcn = createCallbackFcn(app, @RefreshDamperPlotsButtonPushed, true);
            app.RRDamper.Text = 'Rear Right';
            app.DamperStatsTab = uitab(app.TabGroup2);
            app.DamperStatsTab.Title = 'Damper Statistics';
            app.FrontDamperTable = uitable(app.DamperStatsTab);
            app.FrontDamperTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.FrontDamperTable.RowName = {};
            app.RearDamperTable = uitable(app.DamperStatsTab);
            app.RearDamperTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.RearDamperTable.RowName = {};
            app.test3Tab = uitab(app.TabGroup2);
            app.test3Tab.Title = 'test3';
            app.FilesLoadedDropDownLabel = uilabel(app.SingleComp);
            app.FilesLoadedDropDownLabel.Text = {'FilesLoaded'; ''};
            app.FilesLoadedDropDown = uidropdown(app.SingleComp);
            app.FilesLoadedDropDown.Items = {'None'};
            app.FilesLoadedDropDown.Value = 'None';
            app.LoadingFilesLabel = uilabel(app.SingleComp);
            app.LoadingFilesLabel.Visible = 'off';
            app.LoadingFilesLabel.Text = {'Loading Files...'; ''};
            app.DualComparisonTab = uitab(app.TabGroup);
            app.DualComparisonTab.Title = 'Dual Comparison';
            app.LoadDataButton_2 = uibutton(app.DualComparisonTab, 'push');
            app.LoadDataButton_2.Text = {'Load Data'; ''};
            app.FilesLoadedListBox_2Label = uilabel(app.DualComparisonTab);
            app.FilesLoadedListBox_2Label.Text = 'Files Loaded';
            app.FilesLoadedListBox_2 = uilistbox(app.DualComparisonTab);
            app.FilesLoadedListBox_2.Items = {'None'};
            app.FilesLoadedListBox_2.Value = {};
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
        end
    end

    methods (Access = public)

        % Construct app
        function app = F20

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end